classdef ECG_GUI_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ConnectionTab                  matlab.ui.container.Tab
        ConnectionSettingsPanel        matlab.ui.container.Panel
        EstablishConnectionButton      matlab.ui.control.Button
        DisconnectButton               matlab.ui.control.Button
        PortDropDownLabel              matlab.ui.control.Label
        PortDropDown                   matlab.ui.control.DropDown
        ReloadPortsButton              matlab.ui.control.Button
        ProductInformationPanel        matlab.ui.container.Panel
        ManufacturerLabel              matlab.ui.control.Label
        DeviceNameLabel                matlab.ui.control.Label
        SerialNumberLabel              matlab.ui.control.Label
        HardwareVersionLabel           matlab.ui.control.Label
        FirmwareVersionLabel           matlab.ui.control.Label
        SCMVersionLabel                matlab.ui.control.Label
        ProtocolVersionLabel           matlab.ui.control.Label
        LicenseKeyLabel                matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        manufacturer                   matlab.ui.control.EditField
        EditField_2Label               matlab.ui.control.Label
        deviceid                       matlab.ui.control.EditField
        EditField_3Label               matlab.ui.control.Label
        serialnumber                   matlab.ui.control.EditField
        EditField_4Label               matlab.ui.control.Label
        hardwareversion                matlab.ui.control.EditField
        EditField_6Label               matlab.ui.control.Label
        scmversion                     matlab.ui.control.EditField
        EditField_7Label               matlab.ui.control.Label
        protocolversion                matlab.ui.control.EditField
        EditField_8Label               matlab.ui.control.Label
        licensekey                     matlab.ui.control.EditField
        EditField_5Label               matlab.ui.control.Label
        firmwareversion                matlab.ui.control.EditField
        ExitButton                     matlab.ui.control.Button
        ConfigureECGTab                matlab.ui.container.Tab
        MainsfrequencyButtonGroup      matlab.ui.container.ButtonGroup
        Hz50Button                     matlab.ui.control.RadioButton
        Hz60Button                     matlab.ui.control.RadioButton
        SamplingrateButtonGroup        matlab.ui.container.ButtonGroup
        Hz500Button                    matlab.ui.control.RadioButton
        Hz1000Button                   matlab.ui.control.RadioButton
        displayedECGDataPoints         matlab.ui.control.NumericEditField
        DisplayedECGDataPointsLabel    matlab.ui.control.Label
        ECGbuffersizeLabel             matlab.ui.control.Label
        ECGbuffersizeSlider            matlab.ui.control.Slider
        ThresholdderivativeLabel       matlab.ui.control.Label
        ThresholdderivativeSlider      matlab.ui.control.Slider
        MinimumRRintervalLabel         matlab.ui.control.Label
        MinimumRRintervalSlider        matlab.ui.control.Slider
        MaximumRRintervalLabel         matlab.ui.control.Label
        MaximumRRintervalSlider        matlab.ui.control.Slider
        PacemaxdurationLabel           matlab.ui.control.Label
        PacemaxdurationSlider          matlab.ui.control.Slider
        PacemindurationLabel           matlab.ui.control.Label
        PacemindurationSlider          matlab.ui.control.Slider
        ECGChannelsButtonGroup         matlab.ui.container.ButtonGroup
        oneChannel                     matlab.ui.control.RadioButton
        threeSixChannels               matlab.ui.control.RadioButton
        RWavedetectionButtonGroup      matlab.ui.container.ButtonGroup
        rWaveDetectionOn               matlab.ui.control.RadioButton
        rWaveDetectionOff              matlab.ui.control.RadioButton
        PacerdetectionButtonGroup      matlab.ui.container.ButtonGroup
        pacerDetectionOnS              matlab.ui.control.RadioButton
        pacerDetectionOff              matlab.ui.control.RadioButton
        pacerDetectionOnB              matlab.ui.control.RadioButton
        DeadtimeLabel                  matlab.ui.control.Label
        DeadtimeSlider                 matlab.ui.control.Slider
        bufferSizeSpinner              matlab.ui.control.Spinner
        minRRintervalSpinner           matlab.ui.control.Spinner
        maxRRintervalSpinner           matlab.ui.control.Spinner
        thresholdDerivativeSpinner     matlab.ui.control.Spinner
        deadTimeSpinner                matlab.ui.control.Spinner
        paceMinDurationSpinner         matlab.ui.control.Spinner
        paceMaxDurationSpinner         matlab.ui.control.Spinner
        MaximumECGderivativeLabel      matlab.ui.control.Label
        MaximumECGderivativeSpinner    matlab.ui.control.Spinner
        ThresholdsteepSpinnerLabel     matlab.ui.control.Label
        ThresholdsteepSpinner          matlab.ui.control.Spinner
        ECGTab                         matlab.ui.container.Tab
        ECGInformationPanel            matlab.ui.container.Panel
        ReceivedPackagesLabel          matlab.ui.control.Label
        BrokenPackagesLabel            matlab.ui.control.Label
        RunTimesLabel                  matlab.ui.control.Label
        runtime                        matlab.ui.control.NumericEditField
        brokenPackages                 matlab.ui.control.NumericEditField
        receivedPackages               matlab.ui.control.NumericEditField
        LostPackagesLabel              matlab.ui.control.Label
        lostPackages                   matlab.ui.control.NumericEditField
        HeartRateBpmLabel              matlab.ui.control.Label
        heartRate                      matlab.ui.control.NumericEditField
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        ECGGraphPanel_2                matlab.ui.container.Panel
        UIAxes_4                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_6                       matlab.ui.control.UIAxes
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ECGGraphPanel                  matlab.ui.container.Panel
        UIAxes_7                       matlab.ui.control.UIAxes
        BandpassFilter01225HzCheckBox  matlab.ui.control.CheckBox
        ShowRpeaksCheckBox             matlab.ui.control.CheckBox
        ShowpacersCheckBox             matlab.ui.control.CheckBox
    end


    properties (Access = private)
        s
        XValues
        plotting
        Graph_I, Graph_II, Graph_III
        Graph_aVR, Graph_aVL, Graph_aVF
        Graph
        lastPacketNum
        firstCheck
        idxECG
        childrenInConfigureECG
        childrenInECG
        bpFilt
        lineIdxRwave, lineIdxPacer
        linesRwave, linesPacer
        markRpeaks, markPacers
        lineColor
    end

    methods (Access = private)     
        
        function deviceData = handshakeProcedure(app)
            % [INPUT]
            % cell-Array mit Befehlen als string inkl. FC unf FD
            %
            % [OUTPUT]
            % cell-Array mit Antworten als string inkl. FC und FD
            %
            % Führt Handshake Verfahren aus und sammelt alle Informationen/
            % Antworten der Reihe der Befehle nach in deviceData
            
            % Versuche die Datenübertragung des EKG Moduls zu stoppen 
            % falls es noch Daten übertragen sollte, ansonsten mache
            % normal weiter
            try
                stopECGTransmission(app);
            catch ME
                rethrow(ME);
            end
            
            % Handshake Commands
            MAINTENANCE =       '0600080006';
            IDENTIFICATION =    '0000080005';
            LICENSE_KEY =       '0100081000';
            PROTOCOL_VERSION =  '0200080001';
            FIRMWARE_VERSION =  '0300085001';
            SCM_VERSION =       '0400086001';
            HARDWARE_VERSION =  '0500080205';

            commands = {MAINTENANCE,...
                        IDENTIFICATION,...
                        LICENSE_KEY,...
                        PROTOCOL_VERSION,...
                        FIRMWARE_VERSION,...
                        SCM_VERSION,...
                        HARDWARE_VERSION};
            
            deviceData = cell(length(commands),1);
            
            % Sende Commands
            for i = 1:length(commands)
                try
                    deviceData(i) = processCommand(app, commands{i}); % received ist cell Array
                catch ME
                    rethrow(ME)
                end
            end
        end
        
        function receivedHex = processCommand(app, command)
            % [INPUT]
            % Befehl als string exkl. Start- und Endflag (FC und FD)
            % 
            % [OUTPUT]
            % Antwort als string inkl. FC und FD
            %
            % Ablauf:
            % 1. Bereite Befehl zum Senden vor (Octet Stuffing, Checksummenberechnung, Checksummenvertauschung)
            % 2. Sende Befehl
            % 3. Verarbeite Antwort (Octet Stuffing, Checksummenüberprüfung)
            
            % Prüfe Verbindung zum EMB
            if strcmp(app.s.Status,'closed')
                    fopen(app.s);
            end
            
            correctChecksum = false;
            toSend = encodeData(app, command);  % Befehl kodieren
            while ~correctChecksum && isvalid(app.s)
                correctChecksum = false;
                fwrite(app.s, toSend, 'uint8'); % Befehl senden
                t1 = clock;
                receivedBytes = 0;
                while receivedBytes == 0
                    receivedBytes = app.s.BytesAvailable;
                    t2 = clock;
                    t = double(sum((t2(3:6) - t1(3:6)).*[216e3 3600 60 1]));
                    if t >= 0.3    % Nach 300 ms nichts da? -> Gerät reagiert nicht
                        error('The connection attempt timed out. Make sure that the correct port is selected and try again.');
                    end
                end
                % Antwort auslesen
                received = fread(app.s, app.s.BytesAvailable);
                
                % Antwort prüfen
                receivedCommand = checkReceivedCommand(app, received);
                if strcmp(receivedCommand, 'ACK')         % Command wurde acknowledged
                    break                                 % Wenn ACK dann brauch kein OctetStuffing oder Checksummenberechnung durchgeführt werden
                elseif strcmp(receivedCommand, 'NACK')    % Command wurde not acknowledged
                    continue                              % Wiederhole while-loop
                elseif strcmp(receivedCommand, 'REJECT')  % Command wurde rejected
                    stopECGTransmission(app);             % rejected tritt auf wenn EKG noch Daten sendet -> EKG stoppen
                    continue                              % Wiederhole while-loop
                end
                
                % OctetStuffing: receivedHex enthält Start- und Endflag
                receivedHex = octetStuffing(app, {received});
                
                % Checksumme berechnen
                checksumHex = crc16_ccitt(app, receivedHex);
                
                % Checksumme überprüfen
                if strcmp(checksumHex{1}, '0000')
                    correctChecksum = true;        % Abbruchbedingung der while-Schleife
                end
            end
        end
        
        function receivedCommand = checkReceivedCommand(app, recv)
            % [INPUT]
            % Antwort des EKG Moduls als string inkl. FC und FD
            %
            % [OUTPUT]
            % String mit name des empfangenen Commands
            %
            % Prüft, ob das EKG Modul ein NACK, ACK oder REJECT Command geschickt hat
            
            ACK = [0;2];
            NACK = [0;3];
            REJECT = [0;4];
            TRANSMIT = 7;
            SCMVERSION = [96;1];
            IDENT = [0;5];
            HWVERSION = [2;5];
            FWVERSION = [80;1];
            PROTOCOL = [0;1];
            LKEY = [16;0];
            MAINTENANCE = [0;6];
            try
                if isequal(recv(4),TRANSMIT)
                    receivedCommand = 'TRANSMIT';
                elseif isequal(recv(3:4),NACK)
                    receivedCommand = 'NACK';
                elseif isequal(recv(3:4),ACK)
                    receivedCommand = 'ACK';
                elseif isequal(recv(3:4),REJECT)
                    receivedCommand = 'REJECT';
                elseif isequal(recv(3:4),SCMVERSION)
                    receivedCommand = 'SCMVERSION';
                elseif isequal(recv(3:4),IDENT)
                    receivedCommand = 'IDENT';
                elseif isequal(recv(3:4),HWVERSION)
                    receivedCommand = 'HWVERSION';
                elseif isequal(recv(3:4),FWVERSION)
                    receivedCommand = 'FWVERSION';
                elseif isequal(recv(3:4),PROTOCOL)
                    receivedCommand = 'PROTOCOL';
                elseif isequal(recv(3:4),LKEY)
                    receivedCommand = 'LKEY';
                elseif isequal(recv(3:4),MAINTENANCE)
                    receivedCommand = 'MAINTENANCE';
                else
                    receivedCommand = 'UNKOWN';
                end
            catch
                % Erhaltene Antwort war zu kurz. 
                % Es konnte nicht auf Index 3 und 4 zugegriffen werden
                receivedCommand = 'NACK';
            end
        end
        
        function checkSequence(app, packet)
            % [INPUT]
            % Antwort des EKG Moduls als string inkl. FC und FD
            %
            % Prüft auf korrekte Paketreihenfolge
            % Falls nicht in richtiger Reihenfolge dann wird Differenz zu lostPackages hinzugefügt
            
            % Zu kurz (kein Payload) oder kein EKG Paket? 
            % z.B. RwaveDetected Paket [FC,YY,YY,XX,XX,XX,FD] 
            % YY=Command, XX=Nummer des EKG Datasets bei der Rwave war
            % Dataset = eine Antwort von FC bis FD
            if length(packet)<=16 || ~isequal(packet(3:4),[36;7])
                return
            end
            
            currPacketNum = bin2dec([dec2bin(packet(6), 8), dec2bin(packet(5), 8), dec2bin(packet(2), 8)]);
            if ~app.firstCheck
                diff = currPacketNum - app.lastPacketNum;
                app.lostPackages.Value = app.lostPackages.Value + diff - 1;
                app.lastPacketNum = currPacketNum;
            end
            if app.firstCheck
                app.lastPacketNum = 0;
                app.lastPacketNum = currPacketNum;
                app.firstCheck = 0;
            end
        end
        
        function toSend = encodeData(app, hex_input)
            % [INPUT]
            % Befehl an das EKG Modul als string inkl. FC und FD
            %
            % [OUTPUT]
            % Kodierter Befehl als Vektor mit Dezimalzahlen
            % 
            % 1. Checksummenberechnung mit CRC16
            % 2. Befehl + Checksumme zusammenfügen
            % Zusätlich wird auf auftretende FC, FD, FE Octet Stuffing angewendet

            codeword_hex = crc16_ccitt(app,{hex_input});  % Aufruf von CRC Funktion 

            % Prüfe auf FC, FD, FE und führe Octet Stuffing aus
            sendStr = [hex_input codeword_hex{1}];
            
            sendStr = strrep(sendStr, 'FE', 'FEDE');    % Zuerst auf FE prüfen da sonst auch
            sendStr = strrep(sendStr, 'FC', 'FEDC');    % das FE eines bereits ersetzten FEDC,
            sendStr = strrep(sendStr, 'FD', 'FEDD');    % oder FEDD betrachtet und ersetzt wird
            
            resh = reshape(sendStr,[2,length(sendStr)/2])';
            sendDec = hex2dec(resh);  % Zu sendende Informationen nach Octet Stuffing in Dec wandeln

            % FC und FD für kompletten Sendebefehl erweitern
            toSend = [252;...         % FC in Dec
                      sendDec;...    % dieser Befehl wird in Dec gesendet 
                      253];          % FD in Dec
            
            % Checksummen vertauschen
            cksum1 = toSend(end-2, 1);
            toSend(end-2, 1) = toSend(end-1, 1);
            toSend(end-1, 1) = cksum1;
        end
        
        function CRC_hex = crc16_ccitt(app, msg)
            % [INPUT]
            % cell Array mit strings mit/ohne Start- und Endflag (FC FD)
            % 
            % [OUTPUT]
            % CRC_hex: cell mit Checksummen als Hex String
            % 
            % CRC Berechnung erfolgt über Polynomdivision mit XOR. Nenner: Generator 
            % Polynom CRC_POLY. Zähler: CRC (zusammengesetzt aus
            % Anfangsbedingung: 0x84CF in Binärformat, Übertragenen Daten und 16 Nullen)
            % ACHTUNG! Indirekte Methode wird verwendet!
            
            CRC_hex = cell(length(msg),1);
            for i = 1:length(msg)
                try
                    if strcmp(msg{i}(1:2),'FC')
                        % Start- und Endflag (FC und FD) löschen
                        msg{i}(1:2) = [];
                        msg{i}(end-1:end) = [];
                    end
                catch
                    % kann nicht auf Stellen 1:2 oder end-1:end zugreifen
                    % Der String muss länger als 4 Bytes (Dezimalzahlen) sein, sonst fehlerhaft
                    CRC_hex{i} = [];
                    continue
                end
                
%                 INIT_COND = [1 0 0 0 0 1 0 0 1 1 0 0 1 1 1 1];     % Anfangsbedingung: 0x84CF
                CRC_POLY = [1 0 0 0 1 0 0 0 0 0 0 1 0 0 0 0 1];   % Generator Polynom: -> x^16 + x^12 + x^5 + 1
                msg_bin = hexToBinaryVector(msg{i}, 4*length(msg{i}));     % Hex Daten in binären Vektor umwandeln
                % CRC = [INIT_COND, msg_bin, zeros(1,16)]
                CRC = [1 0 0 0 0 1 0 0 1 1 0 0 1 1 1 1, msg_bin, zeros(1,16)];   % Start Polynom
                
                k=1;
                while k+16 <= length(CRC)
                    if CRC(k) == 1
                        CRC(k:k+16) = xor(CRC(k:k+16),CRC_POLY);
                    end
                    k=k+1;
                end
                CRC_hex{i} = binaryVectorToHex(CRC(end-15:end));
            end
        end
    
        function [separatedResponses, lenResponses] = separateResponses(app, data)
            % [INPUT]
            % data: Spaltenvektor mit Dezimalzahlen (Antworten des EKG Moduls)
            %
            % [OUTPUT]
            % separatedResponses: cell-Array mit getrennten Antworten des EKG Moduls
            % lenResponses: Dezimalzahl bis wohin das Array responses gelöscht werden kann
            fcs = find(data == 252);
            fds = find(data == 253);
            if isempty(fcs) % kein FC = keine volle Antwort möglich
                separatedResponses = [];
                lenResponses = -1;
                return
            end
            if isempty(fds) % kein FD? = Rest der Antwort folgt eventuell noch
                separatedResponses = [];
                lenResponses = 0;
                return
            end
            % Erst FD dann FC gefunden?
            if fds(1) < fcs(1)
               lenResponses = fds(1);
               fds(1) = [];
               if isempty(fds)
                  separatedResponses = [];
                  return 
               end
            end

            lenResponses = fds(end);  % Index an dem das letzte gefundene Paket aufhört

            numberOfResponses = length(fds);
            for i = 1:numberOfResponses
                separatedResponses{i} = data(fcs(i):fds(i));
            end
        end
        
        function markRwave(app)
            % Marks the detected R waves and keeps track of them
            % in order to delete them the next time this point in 
            % time is passed/updated
            
            [~,y] = getpoints(app.Graph);
            line_tmp = line(app.UIAxes_7,[app.idxECG app.idxECG],[3e-3 y(end)], 'Color','green');
            app.linesRwave = [app.linesRwave; line_tmp];
            app.lineIdxRwave = [app.lineIdxRwave; app.idxECG-1]; 
        end
    
        function markPacer(app)
            % Marks the detected pacer and keeps track of them
            % in order to delete them the next time this point in 
            % time is passed/updated
            
            [~,y] = getpoints(app.Graph);
            line_tmp = line(app.UIAxes_7,[app.idxECG app.idxECG],[-3e-3 y(end)], 'Color','yellow');
            app.linesPacer = [app.linesPacer; line_tmp];
            app.lineIdxPacer = [app.lineIdxPacer; app.idxECG-1]; 
        end
        
        function deleteMarkedRwave(app)
            % Delete marked Rwaves when new data is written at this point
            
            delete(app.linesRwave(1))
            app.linesRwave(1) = [];
            app.lineIdxRwave(1) = [];
        end
    
        function deleteMarkedPacer(app)
            % Delete marked Pacers when new data is written at this point
            
            delete(app.linesPacer(1))
            app.linesPacer(1) = [];
            app.lineIdxPacer(1) = [];
        end
    
        function updateValues_1channelECG(app, response)
            % [INPUT]
            % Eine einzelne Antwort des EKG Moduls in Dezimalzahlen (Spaltenvektor)
            %
            % Extrahiert den Payload aus einer Antwort des EKG Moduls, dekodiert die
            % Daten und fügt sie dem Array app.ECG hinzu, das zur Darstellung des 
            % EKG-Signals genutzt wird

            if length(response) < app.bufferSizeSpinner.Value && ...
               ~isequal(response(3:4),[114;7]) % Rwave cfm Paket kleiner als bufferSize
                return
            end

            if isequal(response(3:4),[36;7])  % Command für EKG Payload
                % Payload auslesen
                payload = response(10:end-7);
                value_bin = dec2bin(payload, 8);
                ECGvalues = decodeData(app, value_bin);

                % filter the signal
                if app.BandpassFilter01225HzCheckBox.Value == 1
                    ECGvalues = filter(app.bpFilt, ECGvalues);
                end
                
                lenECGdata = length(ECGvalues);
                if app.idxECG+lenECGdata-1 > app.displayedECGDataPoints.Value
                    tooManyValues = (app.idxECG + lenECGdata - 1) - app.displayedECGDataPoints.Value;

                    xRange1 = (app.idxECG:app.displayedECGDataPoints.Value);
                    xRange2 = (1:lenECGdata-tooManyValues);
                    addpoints(app.Graph, xRange1, ECGvalues(xRange2));
                    
                    % NaN verhindert, dass der letzte und erste Punkt 
                    % quer übern Plot miteinander verbunden werden
                    addpoints(app.Graph, 0, NaN);

                    xRange3 = (1:tooManyValues);
                    xRange4 = (lenECGdata-tooManyValues+1:lenECGdata);
                    addpoints(app.Graph, xRange3, ECGvalues(xRange4));
                    
                    % Delete marked Rwave when new data is written over the first marked Rwave
                    if ~isempty(app.lineIdxRwave) && app.idxECG+1 <= app.lineIdxRwave(1) || ~isempty(app.lineIdxRwave) && app.lineIdxRwave(1) <= tooManyValues+1
                        deleteMarkedRwave(app);
                    end
                    % Delete marked Pacer when new data is written over the first marked Pacer
                    if ~isempty(app.lineIdxPacer) && app.idxECG+1 <= app.lineIdxPacer(1) || ~isempty(app.lineIdxPacer) && app.lineIdxPacer(1) <= tooManyValues+1
                        deleteMarkedPacer(app);
                    end
                    app.idxECG = tooManyValues + 1;
                else
                    xRange = app.idxECG:app.idxECG+lenECGdata-1;
                    addpoints(app.Graph, xRange, ECGvalues);
                    
                    % Delete marked Rwave when new data is written over the first marked Rwave
                    if ~isempty(app.lineIdxRwave) && app.idxECG <= app.lineIdxRwave(1) && app.lineIdxRwave(1) <= app.idxECG+lenECGdata-1
                        deleteMarkedRwave(app);
                    end
                    % Delete marked Pacer when new data is written over the first marked Pacer
                    if ~isempty(app.lineIdxPacer) && app.idxECG <= app.lineIdxPacer(1) && app.lineIdxPacer(1) <= app.idxECG+lenECGdata-1
                        deleteMarkedPacer(app);
                    end
                    app.idxECG = app.idxECG + lenECGdata;
                end
                
                % Update heart rate
                app.heartRate.Value = response(7);
            elseif isequal(response(3:4),[114;7])  % Command für Rwave detected
                % Wird nur als Trigger genutzt, auch wenn dieses Paket 
                % die Paketnummer des Zeitpunkts der detektierten R-Zacke
                % als Payload enthält.
                if app.ShowRpeaksCheckBox.Value == 1
                    markRwave(app);
                end
            elseif isequal(response(3:4),[153;7])  % Command für pacer detected
                % Wird nur als Trigger genutzt, auch wenn dieses Paket 
                % die Paketnummer des Zeitpunkts der detektierten Pacers
                % als Payload enthält.
                if app.ShowpacersCheckBox.Value == 1
                    markPacer(app);
                end
            end
        end
        
        function updateValues_3channelECG(app, response)
            % [INPUT]
            % Eine einzelne Antwort des EKG Moduls in Dezimalzahlen (Spaltenvektor)
            %
            % Extrahiert den Payload aus einer Antwort des EKG Moduls, dekodiert die
            % Daten und fügt sie den Arrays hinzu, die zur Darstellung des 
            % der jeweiligen Ableitungen genutzt werden
            
            if length(response) < app.bufferSizeSpinner.Value && ...
               ~isequal(response(3:4),[114;7]) % Rwave cfm Paket kleiner als bufferSize
                return
            end

            if isequal(response(3:4),[36;7])  % Command für EKG Payload
                % Payload auslesen
                payload = response(10:end-7);
                value_bin = dec2bin(payload, 8);
                
                ECGvalues = decodeData(app, value_bin);
                LeadII = ECGvalues(1:2:end);
                LeadIII = ECGvalues(2:2:end);
               
                % Berechnung der anderen Ableitungen aus II und III
                if length(LeadII) == length(LeadIII)
                    LeadI = LeadII - LeadIII;
                    aVR = -(LeadI + LeadII)/2;
                    aVL = (LeadI - LeadII)/2;
                    aVF = (LeadIII + LeadII)/2;
                else 
                    return
                end
                
                if app.BandpassFilter01225HzCheckBox.Value == 1
                    LeadI   = filter(app.bpFilt,LeadI);
                    LeadII  = filter(app.bpFilt,LeadII);
                    LeadIII = filter(app.bpFilt,LeadIII);
                    aVR = filter(app.bpFilt,aVR);
                    aVL = filter(app.bpFilt,aVL);
                    aVF = filter(app.bpFilt,aVF);
                end

                lenECGdata = length(LeadI);

                if app.idxECG+lenECGdata-1 > app.displayedECGDataPoints.Value
                    tooManyValues = (app.idxECG + lenECGdata - 1) - app.displayedECGDataPoints.Value;

                    xRange1 = (app.idxECG : app.displayedECGDataPoints.Value);
                    xRange2 = (1 : tooManyValues);
                    xRange3 = (1 : lenECGdata-tooManyValues);
                    xRange4 = (lenECGdata-tooManyValues+1 : lenECGdata);
                    addpoints(app.Graph_I,   xRange1, LeadI(xRange3));
                    addpoints(app.Graph_II,  xRange1, LeadII(xRange3));
                    addpoints(app.Graph_III, xRange1, LeadIII(xRange3));
                    addpoints(app.Graph_aVR, xRange1, aVR(xRange3));
                    addpoints(app.Graph_aVL, xRange1, aVL(xRange3));
                    addpoints(app.Graph_aVF, xRange1, aVF(xRange3));
                    
                    % Verhindert, dass der letzte und erste Punkt 
                    % quer übern Plot miteinander verbunden werden
                    addpoints(app.Graph_I,   0, NaN);
                    addpoints(app.Graph_II,  0, NaN);
                    addpoints(app.Graph_III, 0, NaN);
                    addpoints(app.Graph_aVR, 0, NaN);
                    addpoints(app.Graph_aVL, 0, NaN);
                    addpoints(app.Graph_aVF, 0, NaN);
                    
                    addpoints(app.Graph_I,   xRange2, LeadI(xRange4));
                    addpoints(app.Graph_II,  xRange2, LeadII(xRange4));
                    addpoints(app.Graph_III, xRange2, LeadIII(xRange4));
                    addpoints(app.Graph_aVR, xRange2, aVR(xRange4));
                    addpoints(app.Graph_aVL, xRange2, aVL(xRange4));
                    addpoints(app.Graph_aVF, xRange2, aVF(xRange4));
                    
                    app.idxECG = tooManyValues + 1;
                else
                    xRange = app.idxECG : app.idxECG+lenECGdata-1;
                    addpoints(app.Graph_I,   xRange, LeadI);
                    addpoints(app.Graph_II,  xRange, LeadII);
                    addpoints(app.Graph_III, xRange, LeadIII);
                    addpoints(app.Graph_aVR, xRange, aVR);
                    addpoints(app.Graph_aVL, xRange, aVL);
                    addpoints(app.Graph_aVF, xRange, aVF);
                    app.idxECG = app.idxECG + lenECGdata;
                end
                
                % Update heart rate
                app.heartRate.Value = response(7);
            elseif isequal(response(3:4),[114;7])  % Command für Rwave detected
                % Wird nur als Trigger genutzt, auch wenn dieses Paket 
                % die Datasetnummer des Zeitpunkts der detektierten R-Zacke
                % als Payload enthält.
                
%                 line(app.UIAxes_7,[app.idxECG app.idxECG],[0.1 app.ECG_LeadI(end)]) % line(app, x,y)
            end
        end

        function plot_1channelECG(app)
            % Plottet das EKG-Signal
            
            time1 = clock;  % Startzeit zur Berechnung der runtime
            rtimeLast = 0;
            app.lineIdxPacer = [];
            app.linesPacer = [];
            app.lineIdxRwave = [];
            app.linesRwave = [];
            responses = [];
            
            % Kontinuierlich Daten auffangen und plotten
            try
                flushinput(app.s);
                while app.plotting && isvalid(app.s)
                    receivedBytes = 0;
                    while receivedBytes == 0
                        receivedBytes = app.s.BytesAvailable;
                    end
                    received = fread(app.s, receivedBytes);     % verfügbare Bytes auslesen
                    responses = [responses; received];  % Antworten aneinanderhängen (Spaltenvektor)

                    [separatedResponses, lenResponses] = separateResponses(app, responses);   % Antworten trennen
                    
                    if isempty(separatedResponses)    % es war keine vollständige Antwort in responses vorhanden
                        if lenResponses == -1
                            responses = [];
                        elseif lenResponses ~= 0      % Zuerst FD gefunden, lösche alles vor und inkl. FD
                            responses(1:lenResponses) = [];
                        end
                        continue
                    end
                    responses(1:lenResponses) = [];   % lösche Antworten die bereits getrennt wurden und schaffe Platz für die nächsten Antworten
                    
                    % recv_hex = cell mit FC FD; recv_dec = cell mit Spaltenvektoren in Dec; separatedResponses = cell mit Antworten in Dec
                    [recv_hex, recv_dec] = octetStuffing(app, separatedResponses); % Octet Stuffing
                    
                    % checksum = cell mit Checksummen
                    checksum = crc16_ccitt(app, recv_hex);   % Checksummenberechnung
                    
                    for j = 1:length(checksum)
                        checkSequence(app, recv_dec{j});    % Reihenfolge der Pakete prüfen
                        app.receivedPackages.Value = app.receivedPackages.Value + 1;
                        
                        % Prüfe Checksummen
                        if strcmp(checksum{j}, '0000')
                            % Update EKG
                            updateValues_1channelECG(app, recv_dec{j});
                            
                            % Update runtime wenn mind. 0,2 Sekunden vergangen sind
                            time2 = clock;
                            rtimeNow = double(sum((time2(3:6) - time1(3:6)).*[216e3 3600 60 1]));
                            if (rtimeNow - rtimeLast) >= 0.2
                                app.runtime.Value = round(rtimeNow,2);
                                rtimeLast = rtimeNow;
                            end
                            drawnow limitrate   % limitrate reduziert Grafik-Updates auf 20 Frames/Sekunde
                        else
                            % Checksumme falsch also update brokenPackages 
                            app.brokenPackages.Value = app.brokenPackages.Value + 1;
                        end
                    end
                end
            catch
                try
                    stopECGTransmission(app)
                    app.StartButton.Enable = 'off';
                    
                    e = errordlg('Something went wrong. The ECG transmission has been terminated.');
                    waitfor(e);
                    
                    app.StartButton.Visible = 'on';
                    app.StartButton.Enable = 'on';
                    app.StopButton.Visible = 'off';
                    app.StopButton.Enable = 'off';
                catch
                    return  % Wenn App gelöscht wird versucht er sonst trotzdem noch die while-Schleife auszuführen
                end
            end
        end
    
        function plot_3channelECG(app)
            % Plottet das EKG-Signal
            
            time1 = clock;  % Startzeit zur Berechnung der runtime
            rtimeLast = 0;
            responses = [];
            
            % Kontinuierlich Daten auffangen und plotten
            try
                flushinput(app.s);
                while app.plotting && isvalid(app.s)
                    
                    receivedBytes = 0;
                    while receivedBytes == 0
                        receivedBytes = app.s.BytesAvailable;
                    end
                    received = fread(app.s, receivedBytes);     % verfügbare Bytes auslesen
                    responses = [responses; received];  % Antworten aneinanderhängen (Spaltenvektor)
                    
                    [separatedResponses, lenResponses] = separateResponses(app, responses);   % Antworten trennen
                    
                    if isempty(separatedResponses)    % es war keine vollständige Antwort in responses vorhanden
                        if lenResponses == -1
                            responses = [];
                        elseif lenResponses ~= 0      % Zuerst FD gefunden, lösche alles vor und inkl. FD
                            responses(1:lenResponses) = [];
                        end
                        continue
                    end
                    responses(1:lenResponses) = [];   % lösche Antworten die bereits getrennt wurden und schaffe Platz für die nächsten Antworten
                    
                    % recv_hex = cell mit FC FD; recv_dec = cell mit Spaltenvektoren in Dec; separatedResponses = cell mit Antworten in Dec
                    [recv_hex, recv_dec] = octetStuffing(app, separatedResponses); % Octet Stuffing
                    
                    % checksum = cell mit Checksummen
                    checksum = crc16_ccitt(app, recv_hex);   % Checksummenberechnung
                    
                    for j = 1:length(checksum)
                        checkSequence(app, recv_dec{j});    % Reihenfolge der Pakete prüfen
                        app.receivedPackages.Value = app.receivedPackages.Value + 1;
                        
                        % Prüfe Checksummen
                        if strcmp(checksum{j}, '0000')
                            % Update EKG
                            updateValues_3channelECG(app, recv_dec{j});
                            
                            % Update runtime wenn mind. 0,2 Sekunden vergangen sind
                            time2 = clock;
                            rtimeNow = double(sum((time2(3:6) - time1(3:6)).*[216e3 3600 60 1]));
                            if (rtimeNow - rtimeLast) >= 0.2
                                app.runtime.Value = round(rtimeNow,2);
                                rtimeLast = rtimeNow;
                            end
                            drawnow limitrate   % limitrate reduziert Grafik-Updates auf 20 Frames/Sekunde
                        else
                            % Checksumme falsch also update brokenPackages
                            app.brokenPackages.Value = app.brokenPackages.Value + 1;
                        end
                    end
                end
            catch
                try
                    stopECGTransmission(app)
                    app.StartButton.Enable = 'off';
                    
                    e = errordlg('Something went wrong. The ECG transmission has been terminated.');
                    waitfor(e);
                    
                    app.StartButton.Visible = 'on';
                    app.StartButton.Enable = 'on';
                    app.StopButton.Visible = 'off';
                    app.StopButton.Enable = 'off';
                catch
                    return  % Wenn App gelöscht wird versucht er sonst trotzdem noch die while-Schleife auszuführen
                end
            end
        end
        
        function[answerStr, answerDec] = octetStuffing(app, receiving)
            % [INPUT]
            % cell-Array mit Spaltenvektoren in Dec
            % 
            % [OUTPUT]
            % answerStr = cell-Array mit strings in Hex inkl. Start- und Endflag
            % answerDec = cell-Array mit einzelnen Antworten (Spaltenvektor in Dec)
            %
            % Führt Octet Stuffing und Checksummenvertauschung aus
            
            answerStr = cell(1,length(receiving));
            answerDec = cell(1,length(receiving));
            replace = [252; 253; 254];        % Wird ein FEDC etc. detektiert wird es durch eine dieser Zahlen ersetzt
            DCddDE = [220; 221; 222];         % Dient zur Überprüfung auf ein nach einem FE folgendes DC, DD oder DE
            
            for i = 1:length(receiving)
                
                if length(receiving{i}) < 4    % fehlerhaftes Paket empfangen
                    answerStr{i} = [];
                    answerDec{i} = [];
                    continue
                end

                % Ersetzung von FEDC, FEDD oder FEDE durch FC, FD oder FE
                for p = 1:length(receiving{i})
                    if p >= length(receiving{i})    % Weil Einträge in receiving gelöscht werden kann p größer werden als das Array
                        break
                    end
                    
                    if receiving{i}(p)==254 && p+1<=length(receiving{i})
                        for o = 1:3
                            if receiving{i}(p+1) == DCddDE(o)
                               receiving{i}(p) = replace(o);    % falls ja setze entsprechend FC,FD oder FE an Stelle p
                               receiving{i}(p+1) = [];
                               break
                            end
                        end
                    end
                end
                % Checksummen der Antwort tauschen
                chksum = receiving{i}(end-1);
                receiving{i}(end-1) = receiving{i}(end-2); 
                receiving{i}(end-2) = chksum;
                
                answerDec = receiving;
                % Erhaltene Informationen in einen Hexzahlen String umwandeln
                answerHex = dec2hex(answerDec{i});
                answerStr{i} = reshape(answerHex',[1,numel(answerHex)]); % reshape von char Array size=(x,2) in einen String
            end
        end
        
        function ECGvalue = decodeData(app, payloadBin)
            % [INPUT]
            % Character Array 8xN N=buffersize
            % 
            % [OUTPUT]
            % Array mit dekodierten EKG Werten
            %
            % Stellt fest, ob es sich um einen 1 oder 2 Byte Wert handelt und berechnet dazu den korrekten EKG Wert
            
            payloadBin = payloadBin';
            pos1 = 1; pos2 = 8;
            bytes = length(payloadBin);
            ECGvalue = [];
            
            for o = 1:bytes
                if pos1 > bytes*8 || pos2 > bytes*8      % Schleife bricht ab wenn pos1 für den nächsten Durchlauf außerhalb des Strings landen würde 
                    break
                end
                check = payloadBin(pos1:pos2);    % speichert eine 8 Bit Folge in Check
                maskBit = check(1, 8);      % liest das MaskBit aus
                signBit = check(1, 1);      % liest das SignBit aus
                if strcmp(maskBit, '0')     % prüft ob MaskBit einer 0 entspricht (strcmp gibt eine 1 bei Übereinstimmung aus)
                    check(8) = [];          % löscht das MaskBit
                    if strcmp(signBit, '0') % prüft ob SignBit einer 0 entspricht
                        sign = '00000000';  % ergänzt den 1 Byte Value um Nullen entsprechend dem SignBit, sodass ein 15 Bit Wert entsteht
                    else
                        sign = '11111111';  % ergänzt den 1 Byte Value um Einsen entsprechend dem SignBit, sodass ein 15 Bit Wert entsteht
                    end
                    value = bin2dec([sign check]);
                    pos1 = pos1 + 8;        % pos1 und pos2 erhalten neue Werte um die nächsten 8 Bit im nächsten Durchlauf zu betrachten
                    pos2 = pos2 + 8;
                elseif strcmp(maskBit, '1') && pos2+8 <= bytes*8 
                    check = payloadBin(pos1:pos2+8);
                    check(8) = [];
                    value = bin2dec(check);
                    pos1 = pos1 + 16;
                    pos2 = pos2 + 16;
                end
                
                if value > 2^14
                    % dem Dezimalwert wird ein Offset abgezogen, welcher auf ihm lastet wenn der Wert vorher höher als 2^15 war
                    % und Skalierung erfolgt mit 2,86e-6 Volt/Bit
                    ECGvalue(o) = (value - 2^15)*2.86e-6;
                else
                    ECGvalue(o) = value*2.86e-6;   
                end
            end
        end
        
        function startECGTransmission(app)
            % Startet die EKG Messung
            
            if strcmp(app.s.Status,'closed')
                fopen(app.s);
            end
            
            command = '00050901';
            toSend = encodeData(app, command); % Wandelt eingegebene Hexzahlen + Checksum nach Checksum Berechnung in Dec Zahlen um und vertauscht die Checksum Bytes
            while isvalid(app.s)
                % EKG muss vor dem Senden eines neuen Startbefehls neu konfiguriert werden
                configureECG(app)
                
                app.plotting = true;
                fwrite(app.s, toSend, 'uint8'); % Befehl senden
                receivedBytes = 0;
                while receivedBytes == 0
                    receivedBytes = app.s.BytesAvailable;
                end
                received = fread(app.s, receivedBytes); % Antwort auslesen
                receivedCommand = checkReceivedCommand(app, received);  % Command der Antwort prüfen
                if strcmp(receivedCommand, 'ACK')   % ACK? -> EKG Messung gestartet
                    break
                % Tritt einer dieser Fälle ein, übertragt das Modul noch Daten und muss gestoppt werden
                elseif strcmp(receivedCommand, 'TRANSMIT') || strcmp(receivedCommand, 'NACK') ||... % Eins hier von? -> EKG nicht beendet -> stoppe Datenübertragung
                       strcmp(receivedCommand, 'REJECT') || strcmp(receivedCommand, 'UNKOWN')
                    try
                       stopECGTransmission(app)
                    catch
                    end
                    continue    % Versuche Befehl erneut zu senden
                else
                    continue    % Versuche Befehl erneut zu senden
                end
            end
        end
        
        function stopECGTransmission(app)
            % Stoppt die EKG Messung
            
            if strcmp(app.s.Status,'closed')
                fopen(app.s);
            end
            
            app.plotting = false;   % Verhindert das weitere Ausführen der Schleife in den Update Funktionen -> sonst bleibt Programm in Endlosschleife hängen (receivedBytes)
            stop = '00050900';
            toSend = encodeData(app, stop); % Wandelt eingegebene Hexzahlen + Checksum nach Checksum Berechnung in Dec Zahlen um und vertauscht die Checksum Bytes
            while isvalid(app.s)
                fwrite(app.s, toSend, 'uint8'); % Senden
                receivedBytes = 0;
                t1 = clock;
                while receivedBytes == 0
                    receivedBytes = app.s.BytesAvailable;
                    t2 = clock;
                    t = double(sum((t2(3:6) - t1(3:6)).*[216e3 3600 60 1]));
                    if t >= 2
                        error('The connection attempt timed out. Is the correct port selected?')
                    end
                end
                received = fread(app.s, receivedBytes);
                receivedCommand = checkReceivedCommand(app, received);
                if strcmp(receivedCommand, 'ACK') || strcmp(receivedCommand, 'REJECT')  % Wenn REJECT dann Modul ist bereits gestoppt
                    pause(0.2)
                    return
                else
                    pause(0.2);
                    continue
                end
            end
        end
    
        function configureECG(app)
            
%             wo = 50/(1000/2);  bw = wo/35;
%             [app.bFilt,app.aFilt] = iirnotch(wo,bw);
            
            % Konfiguriert das EKG
            
            % ANALOG_REQ
            if app.Hz1000Button.Value == 1
                samplingFreq = '0A';    % 1000 Hz
            else
                samplingFreq = '05';    % 500 Hz
            end
            
            if app.Hz50Button.Value == 1
                mainsFreq = '32';   % 50 Hz
            else
                mainsFreq = '3C';   % 60 Hz
            end
            
            if app.oneChannel.Value == 1
                channels = '01';
                app.ECGGraphPanel.Visible = 'on';
                app.ECGGraphPanel_2.Visible = 'off';
            else
                channels = '02';
                app.ECGGraphPanel.Visible = 'off';
                app.ECGGraphPanel_2.Visible = 'on';
            end
            
            % LATENCY_REQ
            buffersize = dec2hex(app.bufferSizeSpinner.Value);
            
            % R_WAVE_DETECTION_REQ
            if app.rWaveDetectionOn.Value == 1
                rwaveDetection = '01';   
            else
                rwaveDetection = '00';   
            end
            
            % 2 Bytes erwartet (LSB first)
            td_tmp = dec2hex(app.thresholdDerivativeSpinner.Value, 4); % 40->'0028'
            td = [td_tmp(3:4) td_tmp(1:2)]; % LSB first -> '2800'
            
            minRR_tmp = dec2hex(app.minRRintervalSpinner.Value, 4);
            minRR = [minRR_tmp(3:4) minRR_tmp(1:2)];
            
            maxRR_tmp = dec2hex(app.maxRRintervalSpinner.Value, 4);
            maxRR = [maxRR_tmp(3:4) maxRR_tmp(1:2)]; 
            
            ECGDerivative_tmp = dec2hex(app.MaximumECGderivativeSpinner.Value, 4);
            ECGDerivative = [ECGDerivative_tmp(3:4) ECGDerivative_tmp(1:2)]; 
            
            % PACER_DETECTION_REQ
            if app.pacerDetectionOnS.Value == 1
                pDetection = '01'; % 1 (LSB first)
            elseif app.pacerDetectionOnB.Value == 1
                pDetection = '02';
            else
                pDetection = '00';
            end
            
            maxPaceDuration_tmp = dec2hex(app.paceMaxDurationSpinner.Value * 4, 4); % *4, weil samples übergebn werden müssen und 1 sample 0,25ms sind
            maxPaceDuration = [maxPaceDuration_tmp(3:4) maxPaceDuration_tmp(1:2)];
            
            minPaceDuration_tmp = dec2hex(app.paceMinDurationSpinner.Value * 4, 4);
            minPaceDuration = [minPaceDuration_tmp(3:4) minPaceDuration_tmp(1:2)];
            
            ts_tmp = dec2hex(app.ThresholdsteepSpinner.Value, 8);
            ts = [ts_tmp(7:8) ts_tmp(5:6) ts_tmp(3:4) ts_tmp(1:2)];
            
            dt_tmp = dec2hex(app.deadTimeSpinner.Value, 4);
            dt = [dt_tmp(3:4) dt_tmp(1:2)];
            
            % Einstellungen
            CONFIG_ANALOG_REQ = ['000109' channels samplingFreq mainsFreq];
            CONFIG_LATENCY_REQ = ['010909' buffersize];
            CONFIG_RWAVE_DETECTION_REQ = ['020209' rwaveDetection td minRR maxRR ECGDerivative];
            CONFIG_PACER_DETECTION_REQ = ['030409' pDetection maxPaceDuration minPaceDuration ts dt '00'];
            
            commands = {CONFIG_ANALOG_REQ,...
                        CONFIG_LATENCY_REQ,...
                        CONFIG_RWAVE_DETECTION_REQ,...
                        CONFIG_PACER_DETECTION_REQ};
            
            % Sende Commands zur Konfiguration
            for i = 1:length(commands)
                processCommand(app, commands{i});
            end
        end
        
        function children = getAllOnChildren(app, tab)
            children = findall(tab.Children,'Enable','on');
        end
    
        function disableChildren(app, children)
            for i = 1:length(children)
                children(i).Enable = 'off';
            end
        end
    
        function enableChildren(app, children)
            for i = 1:length(children)
                children(i).Enable = 'on';
            end
        end
    
        function loadAvailablePorts(app)
            app.PortDropDown.Items = seriallist;
            app.PortDropDown.ItemsData = 1:length(app.PortDropDown.Items);
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
            
            % 'off' damit die Buttons nicht als children in getAllOnChildren() gefunden werden
            % Enable und Visible sollen bei diesen manuell gesteuert werden
            app.StopButton.HandleVisibility = 'off';
            app.StartButton.HandleVisibility = 'off';
            app.ExitButton.HandleVisibility = 'off';
        
            app.DisconnectButton.Visible = 'off';
            app.StopButton.Visible = 'off';
            app.StartButton.Enable = 'off';
            app.ExitButton.Enable = 'off';
            
            app.ECGGraphPanel_2.Visible = 'off';
            
            % Disable interactivity
            app.runtime.Enable = 'off';
            app.brokenPackages.Enable = 'off';
            app.receivedPackages.Enable = 'off';
            app.lostPackages.Enable = 'off';
            app.heartRate.Enable = 'off';
            
            % Make tabs look like they are not interactible
            app.ConfigureECGTab.ForegroundColor = [.6 .6 .6];
            app.ECGTab.ForegroundColor = [.6 .6 .6];
            
            % Get all enabled graphics
            % By default disabled graphics won't be affected by en-/disabling these children
            app.childrenInConfigureECG = getAllOnChildren(app, app.ConfigureECGTab);
            app.childrenInECG = getAllOnChildren(app, app.ECGTab);
            
            % Disable interactivity with all enabled children of these tabs
            disableChildren(app, app.childrenInConfigureECG);
            disableChildren(app, app.childrenInECG);
            app.ExitButton.Enable = 'on';
            
            % Initialize values
            app.lostPackages.Value = 0;
            app.firstCheck = 1;
            app.idxECG = 1;
            
            % Get available ports
            loadAvailablePorts(app)
            
            % Major tick labels
            app.DeadtimeSlider.MajorTickLabels = {};
            app.ThresholdderivativeSlider.MajorTickLabels = {};
            app.MinimumRRintervalSlider.MajorTickLabels = {};
            app.MaximumRRintervalSlider.MajorTickLabels = {};
            app.ECGbuffersizeSlider.MajorTickLabels = {};
            app.PacemaxdurationSlider.MajorTickLabels = {};
            app.PacemindurationSlider.MajorTickLabels = {};
            
            % Major ticks
            app.ECGbuffersizeSlider.MajorTicks = 10:20:100;
            app.PacemaxdurationSlider.MajorTicks = 1.5:0.5:8;
            
            % Minor ticks
            app.ECGbuffersizeSlider.MinorTicks = 5:5:100;
            app.PacemaxdurationSlider.MinorTicks = 1.5:0.25:8;
            
            % Spinner steps
            app.paceMaxDurationSpinner.Step = 0.25;
            app.paceMinDurationSpinner.Step = 0.25;

            % Spinner display format
            app.ThresholdsteepSpinner.ValueDisplayFormat = '%d mV/ms';
            app.maxRRintervalSpinner.ValueDisplayFormat = '%d ms';
            app.minRRintervalSpinner.ValueDisplayFormat = '%d ms';
            app.deadTimeSpinner.ValueDisplayFormat = '%d ms';
            app.paceMaxDurationSpinner.ValueDisplayFormat = '%.2f ms';
            app.paceMinDurationSpinner.ValueDisplayFormat = '%.2f ms';
            
            % Limits
            app.DeadtimeSlider.Limits = [0 400];
            app.ThresholdderivativeSlider.Limits = [30 70];
            app.MinimumRRintervalSlider.Limits = [200 2499];
            app.MaximumRRintervalSlider.Limits = [201 2500];
            app.ECGbuffersizeSlider.Limits = [5 100];
            app.PacemaxdurationSlider.Limits = [1.5 8];
            app.PacemindurationSlider.Limits = [0.25 1.5];
            app.MaximumECGderivativeSpinner.Limits = [0 65534];
            app.bufferSizeSpinner.Limits = [5 100];
            app.maxRRintervalSpinner.Limits = [201 2500];
            app.minRRintervalSpinner.Limits = [200 2499];
            app.thresholdDerivativeSpinner.Limits = [30 70];
            app.deadTimeSpinner.Limits = [0 400];
            app.paceMinDurationSpinner.Limits = [0.25 1.5];
            app.paceMaxDurationSpinner.Limits = [1.5 8];
            app.ThresholdsteepSpinner.Limits = [0 655];
            
            % Default values
            app.DeadtimeSlider.Value = 20;
            app.ThresholdderivativeSlider.Value = 40;
            app.MinimumRRintervalSlider.Value = 200;
            app.MaximumRRintervalSlider.Value = 2500;
            app.ECGbuffersizeSlider.Value = 100;
            app.PacemaxdurationSlider.Value = 4;
            app.PacemindurationSlider.Value = 0.5;
            app.deadTimeSpinner.Value = 20;
            app.thresholdDerivativeSpinner.Value = 40;
            app.minRRintervalSpinner.Value = 200;
            app.maxRRintervalSpinner.Value = 2500;
            app.bufferSizeSpinner.Value = 100;
            app.paceMaxDurationSpinner.Value = 4;
            app.paceMinDurationSpinner.Value = 0.5;
            app.MaximumECGderivativeSpinner.Value = 253;
            app.ThresholdsteepSpinner.Value = 2;
            app.pacerDetectionOnS.Value = 1;
            app.rWaveDetectionOn.Value = 1;
            app.oneChannel.Value = 1;
            app.Hz1000Button.Value = 1;
            app.Hz50Button.Value = 1;
            app.pacerDetectionOff.Value = 1;
            app.rWaveDetectionOn.Value = 1;
            app.displayedECGDataPoints.Value = 3000;
            
            % Settings for UIAxes
            app.UIAxes.YLabel.String   = 'I';
            app.UIAxes_2.YLabel.String = 'II';
            app.UIAxes_3.YLabel.String = 'III';
            app.UIAxes_4.YLabel.String = 'aVR';
            app.UIAxes_5.YLabel.String = 'aVL';
            app.UIAxes_6.YLabel.String = 'aVF';
            app.UIAxes_7.YLabel.String = 'I';
            
            app.UIAxes.XLabel.String   = [];
            app.UIAxes_2.XLabel.String = [];
            app.UIAxes_3.XLabel.String = [];
            app.UIAxes_4.XLabel.String = [];
            app.UIAxes_5.XLabel.String = [];
            app.UIAxes_6.XLabel.String = [];
            app.UIAxes_7.XLabel.String = [];
            
            app.UIAxes.XTick   = [];
            app.UIAxes_2.XTick = [];
            app.UIAxes_3.XTick = [];
            app.UIAxes_4.XTick = [];
            app.UIAxes_5.XTick = [];
            app.UIAxes_6.XTick = [];
            app.UIAxes_7.XTick = [];

            app.UIAxes.YLimMode = 'manual';
            app.UIAxes_2.YLimMode = 'manual';
            app.UIAxes_3.YLimMode = 'manual';
            app.UIAxes_4.YLimMode = 'manual';
            app.UIAxes_5.YLimMode = 'manual';
            app.UIAxes_6.YLimMode = 'manual';
            app.UIAxes_7.YLimMode = 'manual';
            
            app.UIAxes.YLim   = [-3e-3 3e-3];
            app.UIAxes_2.YLim = [-3e-3 3e-3];
            app.UIAxes_3.YLim = [-3e-3 3e-3];
            app.UIAxes_4.YLim = [-3e-3 3e-3];
            app.UIAxes_5.YLim = [-3e-3 3e-3];
            app.UIAxes_6.YLim = [-3e-3 3e-3];
            app.UIAxes_7.YLim = [-3e-3 3e-3];
            
            grid(app.UIAxes  ,'on');
            grid(app.UIAxes_2,'on');
            grid(app.UIAxes_3,'on');
            grid(app.UIAxes_4,'on');
            grid(app.UIAxes_5,'on');
            grid(app.UIAxes_6,'on');
            grid(app.UIAxes_7,'on');
        end

        % Button pushed function: EstablishConnectionButton
        function EstablishConnectionButtonPushed(app, event)
            app.EstablishConnectionButton.Enable = 'off';
            pause(0.0001)
            app.PortDropDown.Enable = 'off';
            port = app.PortDropDown.Items{app.PortDropDown.Value};
            
            % Erstelle animatedline Objekte
            app.lineColor = 'black';
            app.Graph_I   = animatedline(app.UIAxes,   'MaximumNumPoints',app.displayedECGDataPoints.Value, 'Color', app.lineColor);
            app.Graph_II  = animatedline(app.UIAxes_2, 'MaximumNumPoints',app.displayedECGDataPoints.Value, 'Color', app.lineColor);
            app.Graph_III = animatedline(app.UIAxes_3, 'MaximumNumPoints',app.displayedECGDataPoints.Value, 'Color', app.lineColor);
            app.Graph_aVR = animatedline(app.UIAxes_4, 'MaximumNumPoints',app.displayedECGDataPoints.Value, 'Color', app.lineColor);
            app.Graph_aVL = animatedline(app.UIAxes_5, 'MaximumNumPoints',app.displayedECGDataPoints.Value, 'Color', app.lineColor);
            app.Graph_aVF = animatedline(app.UIAxes_6, 'MaximumNumPoints',app.displayedECGDataPoints.Value, 'Color', app.lineColor);
            app.Graph     = animatedline(app.UIAxes_7, 'MaximumNumPoints',app.displayedECGDataPoints.Value, 'Color', app.lineColor);
            
            % Nullline beim Start anzeigen
            nullLine = zeros(app.displayedECGDataPoints.Value, 1);
            range = 1:app.displayedECGDataPoints.Value;
            addpoints(app.Graph,     range, nullLine);
            addpoints(app.Graph_I,   range, nullLine);
            addpoints(app.Graph_II,  range, nullLine);
            addpoints(app.Graph_III, range, nullLine);
            addpoints(app.Graph_aVR, range, nullLine);
            addpoints(app.Graph_aVL, range, nullLine);
            addpoints(app.Graph_aVF, range, nullLine);
            
            addpoints(app.Graph,     0, NaN);
            addpoints(app.Graph_I,   0, NaN);
            addpoints(app.Graph_II,  0, NaN);
            addpoints(app.Graph_III, 0, NaN);
            addpoints(app.Graph_aVR, 0, NaN);
            addpoints(app.Graph_aVL, 0, NaN);
            addpoints(app.Graph_aVF, 0, NaN);
            
            % Wenn der gewählte Port bereits konfigueriert aber nicht geschlossen wurde schließe und lösche ihn
            % Verhindert, dass das Gerät von Matlab nicht angesprochen werden kann
            if ~isempty(instrfind('Port',port))
                fclose(instrfind('Port',port));
                delete(instrfind('Port',port));
            end
            
            % Konfiguriere Serial Port
            app.s = serial(port,'BaudRate',115200);
            flushinput(app.s);
            app.s.InputBufferSize = 1024;
            
            % Handshake
            try
                deviceData = handshakeProcedure(app);
            catch ME
               e = errordlg(ME.message);
               waitfor(e);
               app.PortDropDown.Enable = 'on';
               app.EstablishConnectionButton.Enable = 'on';
               return
            end
            if isempty(deviceData)
                app.PortDropDown.Enable = 'on';
                app.EstablishConnectionButton.Enable = 'on';
                return
            end
            
            % MANUFACTURER
            if strcmp(deviceData{2}(9:10), '01')
                app.manufacturer.Value = 'Corscience';
            else
                app.manufacturer.Value = 'Unknown Manufacturer';
            end
            
            % DEVICE ID
            if strcmp(deviceData{2}(11:12), '2A')
                app.deviceid.Value = 'EMB';
            else
                app.deviceid.Value = 'Unknown Device';
            end
            
            % SERIAL NUMBER
            app.serialnumber.Value = deviceData{2}(13:end-6);
            
            % LICENSE KEY
            app.licensekey.Value = deviceData{3}(9:end-8);
            
            % PROTOCOL VERSION
            app.protocolversion.Value = ['0x' deviceData{4}(9:10)];
            
            % FIRMWARE VERSION
            fw = '';
            fwVer = deviceData{5}(9:end-11);
            for i = 1:2:length(fwVer)-1
               fw = [fw char(hex2dec(fwVer(i:i+1)))]; 
            end
            app.firmwareversion.Value = fw;
            
            % SCM VERSION
            scm = '';
            scmrev = deviceData{6}(9:end-6);
            for i = 1:2:length(scmrev)-1
               scm = [scm char(hex2dec(scmrev(i:i+1)))]; 
            end
            app.scmversion.Value = scm;
            
            % HARDWARE VERSION
            app.hardwareversion.Value = num2str(hex2dec(deviceData{7}(9:10)));
            
            app.DisconnectButton.Visible = 'on';
            app.EstablishConnectionButton.Visible = 'off';
            app.StartButton.Enable = 'on';
            app.ConfigureECGTab.ForegroundColor = [0 0 0];
            app.ECGTab.ForegroundColor = [0 0 0];
            enableChildren(app, app.childrenInConfigureECG);
            enableChildren(app, app.childrenInECG);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            try
                stopECGTransmission(app);
            catch
            end
            
            flushinput(app.s);
            fclose(app.s);
            
            disableChildren(app, app.childrenInConfigureECG);
            app.ConfigureECGTab.ForegroundColor = [.6 .6 .6];
            app.ECGTab.ForegroundColor = [.6 .6 .6];
            
            app.manufacturer.Value = '';
            app.deviceid.Value = '';
            app.serialnumber.Value = '';
            app.hardwareversion.Value = '';
            app.firmwareversion.Value = '';
            app.scmversion.Value = '';
            app.protocolversion.Value = '';
            app.licensekey.Value = '';

            app.brokenPackages.Value = 0;
            app.receivedPackages.Value = 0;
            
            app.EstablishConnectionButton.Visible = 'on';
            app.EstablishConnectionButton.Enable = 'on';
            app.DisconnectButton.Visible = 'off';
            app.StartButton.Visible = 'on';
            app.StartButton.Enable = 'off';
            app.StopButton.Visible = 'off';
            app.StopButton.Enable = 'off';
            app.PortDropDown.Enable = 'on';
            
            % Clear axes
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            cla(app.UIAxes_4)
            cla(app.UIAxes_5)
            cla(app.UIAxes_6)
            cla(app.UIAxes_7)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.DisconnectButton.Enable = 'off';
            disableChildren(app, app.childrenInConfigureECG);
            app.displayedECGDataPoints.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.ExitButton.Enable  = 'off';
            app.brokenPackages.Value   = 0;
            app.receivedPackages.Value = 0;
            app.lostPackages.Value     = 0;
            app.runtime.Value          = 0;
            app.firstCheck = 1;
            app.idxECG = 1;
            
            % Design bandpass filter
            if app.Hz500Button == 1
                app.bpFilt = designfilt('bandpassfir','FilterOrder',10, ...
                                        'CutoffFrequency1',0.12,'CutoffFrequency2',25, ...
                                        'SampleRate',500);
            else
                app.bpFilt = designfilt('bandpassfir','FilterOrder',20, ...
                                        'CutoffFrequency1',0.12,'CutoffFrequency2',25, ...
                                        'SampleRate',1000);
            end
            
            % Clear axes
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            cla(app.UIAxes_4)
            cla(app.UIAxes_5)
            cla(app.UIAxes_6)
            cla(app.UIAxes_7)
            
            % Erstelle animatedline Objekte
            app.lineColor = 'black';
            app.Graph_I   = animatedline(app.UIAxes,   'MaximumNumPoints',app.displayedECGDataPoints.Value, 'Color', app.lineColor);
            app.Graph_II  = animatedline(app.UIAxes_2, 'MaximumNumPoints',app.displayedECGDataPoints.Value, 'Color', app.lineColor);
            app.Graph_III = animatedline(app.UIAxes_3, 'MaximumNumPoints',app.displayedECGDataPoints.Value, 'Color', app.lineColor);
            app.Graph_aVR = animatedline(app.UIAxes_4, 'MaximumNumPoints',app.displayedECGDataPoints.Value, 'Color', app.lineColor);
            app.Graph_aVL = animatedline(app.UIAxes_5, 'MaximumNumPoints',app.displayedECGDataPoints.Value, 'Color', app.lineColor);
            app.Graph_aVF = animatedline(app.UIAxes_6, 'MaximumNumPoints',app.displayedECGDataPoints.Value, 'Color', app.lineColor);
            app.Graph     = animatedline(app.UIAxes_7, 'MaximumNumPoints',app.displayedECGDataPoints.Value, 'Color', app.lineColor);
            
            % Nullline beim Start anzeigen
            nullLine = zeros(app.displayedECGDataPoints.Value, 1);
            range = 1:app.displayedECGDataPoints.Value;
            addpoints(app.Graph,     range, nullLine);
            addpoints(app.Graph_I,   range, nullLine);
            addpoints(app.Graph_II,  range, nullLine);
            addpoints(app.Graph_III, range, nullLine);
            addpoints(app.Graph_aVR, range, nullLine);
            addpoints(app.Graph_aVL, range, nullLine);
            addpoints(app.Graph_aVF, range, nullLine);
            
            addpoints(app.Graph,     0, NaN);
            addpoints(app.Graph_I,   0, NaN);
            addpoints(app.Graph_II,  0, NaN);
            addpoints(app.Graph_III, 0, NaN);
            addpoints(app.Graph_aVR, 0, NaN);
            addpoints(app.Graph_aVL, 0, NaN);
            addpoints(app.Graph_aVF, 0, NaN);
            
            app.ExitButton.Enable = 'on';
            app.StopButton.Visible = 'on';
            app.StopButton.Enable = 'on';
            app.StartButton.Visible = 'off';
            app.DisconnectButton.Enable = 'on';
            
            % Starte EKG Messung
            startECGTransmission(app);
            
            % Plotte EKG Signal
            if app.oneChannel.Value == 1
                plot_1channelECG(app);
            else
                plot_3channelECG(app);
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Wenn EKG Modul noch Daten übertragen sollte stoppe es vorher
            try
                stopECGTransmission(app)
            catch
            end
            
            % Wenn die App verlassen wird ohne app.s jemals 
            % als Serial Port zu intialisieren, wirft das einen Error
            try
                stopasync(app.s);
                flushinput(app.s);
                fclose(app.s);
            catch
            end
            UIFigureCloseRequest(app, event);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.StopButton.Enable = 'off';
            
            stopECGTransmission(app)
            
            enableChildren(app, app.childrenInConfigureECG);
            app.StartButton.Visible = 'on';
            app.StopButton.Visible = 'off';
            app.StartButton.Enable = 'on';
            app.DisconnectButton.Enable = 'on';
            app.displayedECGDataPoints.Enable = 'on';
            
            flushinput(app.s);
            fclose(app.s);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                delete(app)
            catch
            end
        end

        % Value changing function: MinimumRRintervalSlider
        function MinimumRRintervalSliderValueChanging(app, event)
            app.minRRintervalSpinner.Value = round(event.Value,0);
            if event.Value >= app.maxRRintervalSpinner.Value
                app.maxRRintervalSpinner.Value = app.minRRintervalSpinner.Value + 1;
                app.MaximumRRintervalSlider.Value = app.maxRRintervalSpinner.Value;
            end
        end

        % Value changing function: MaximumRRintervalSlider
        function MaximumRRintervalSliderValueChanging(app, event)
            app.maxRRintervalSpinner.Value = round(event.Value,0);
            if event.Value <= app.minRRintervalSpinner.Value
                app.minRRintervalSpinner.Value = app.maxRRintervalSpinner.Value - 1;
                app.MinimumRRintervalSlider.Value = app.minRRintervalSpinner.Value;
            end
        end

        % Value changing function: PacemaxdurationSlider
        function PacemaxdurationSliderValueChanging(app, event)
            step = 0.25;
            app.paceMaxDurationSpinner.Value = round(event.Value / step,0) * step;
        end

        % Value changing function: PacemindurationSlider
        function PacemindurationSliderValueChanging(app, event)
            step = 0.25;
            app.paceMinDurationSpinner.Value = round(event.Value / step,0) * step;
        end

        % Value changing function: ThresholdderivativeSlider
        function ThresholdderivativeSliderValueChanging(app, event)
            app.thresholdDerivativeSpinner.Value = round(event.Value,0);
        end

        % Value changing function: ECGbuffersizeSlider
        function ECGbuffersizeSliderValueChanging(app, event)
            app.bufferSizeSpinner.Value = round(event.Value,0);
        end

        % Value changing function: DeadtimeSlider
        function DeadtimeSliderValueChanging(app, event)
            app.deadTimeSpinner.Value = round(event.Value,0);
        end

        % Value changing function: maxRRintervalSpinner
        function maxRRintervalSpinnerValueChanging(app, event)
            app.MaximumRRintervalSlider.Value = round(event.Value,0);
        end

        % Value changing function: paceMaxDurationSpinner
        function paceMaxDurationSpinnerValueChanging(app, event)
            app.PacemaxdurationSlider.Value = event.Value;
        end

        % Value changing function: minRRintervalSpinner
        function minRRintervalSpinnerValueChanging(app, event)
            app.MinimumRRintervalSlider.Value = round(event.Value,0);
        end

        % Value changing function: paceMinDurationSpinner
        function paceMinDurationSpinnerValueChanging(app, event)
            app.PacemindurationSlider.Value = event.Value;
        end

        % Value changing function: thresholdDerivativeSpinner
        function thresholdDerivativeSpinnerValueChanging(app, event)
            app.ThresholdderivativeSlider.Value = round(event.Value,0);
        end

        % Value changing function: bufferSizeSpinner
        function bufferSizeSpinnerValueChanging(app, event)
            app.ECGbuffersizeSlider.Value = round(event.Value,0);
        end

        % Value changing function: deadTimeSpinner
        function deadTimeSpinnerValueChanging(app, event)
            app.DeadtimeSlider.Value = round(event.Value,0);
        end

        % Button pushed function: ReloadPortsButton
        function ReloadPortsButtonPushed(app, event)
            loadAvailablePorts(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConnectionTab = uitab(app.TabGroup);
            app.ConnectionTab.Title = 'Connection';
            app.ConnectionSettingsPanel = uipanel(app.ConnectionTab);
            app.ConnectionSettingsPanel.Title = 'Connection Settings';
            app.EstablishConnectionButton = uibutton(app.ConnectionSettingsPanel, 'push');
            app.EstablishConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @EstablishConnectionButtonPushed, true);
            app.EstablishConnectionButton.Text = 'Establish Connection';
            app.DisconnectButton = uibutton(app.ConnectionSettingsPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.PortDropDownLabel = uilabel(app.ConnectionSettingsPanel);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.ConnectionSettingsPanel);
            app.PortDropDown.Items = {};
            app.PortDropDown.Value = {};
            app.ReloadPortsButton = uibutton(app.ConnectionSettingsPanel, 'push');
            app.ReloadPortsButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadPortsButtonPushed, true);
            app.ReloadPortsButton.Text = 'Reload Ports';
            app.ProductInformationPanel = uipanel(app.ConnectionTab);
            app.ProductInformationPanel.Title = 'Product Information';
            app.ManufacturerLabel = uilabel(app.ProductInformationPanel);
            app.ManufacturerLabel.Text = 'Manufacturer';
            app.DeviceNameLabel = uilabel(app.ProductInformationPanel);
            app.DeviceNameLabel.Text = 'Device Name';
            app.SerialNumberLabel = uilabel(app.ProductInformationPanel);
            app.SerialNumberLabel.Text = 'Serial Number';
            app.HardwareVersionLabel = uilabel(app.ProductInformationPanel);
            app.HardwareVersionLabel.Text = 'Hardware Version';
            app.FirmwareVersionLabel = uilabel(app.ProductInformationPanel);
            app.FirmwareVersionLabel.Text = 'Firmware Version';
            app.SCMVersionLabel = uilabel(app.ProductInformationPanel);
            app.SCMVersionLabel.Text = 'SCM Version';
            app.ProtocolVersionLabel = uilabel(app.ProductInformationPanel);
            app.ProtocolVersionLabel.Text = 'Protocol Version';
            app.LicenseKeyLabel = uilabel(app.ProductInformationPanel);
            app.LicenseKeyLabel.Text = 'License Key';
            app.Label = uilabel(app.ProductInformationPanel);
            app.Label.Text = '';
            app.manufacturer = uieditfield(app.ProductInformationPanel, 'text');
            app.EditField_2Label = uilabel(app.ProductInformationPanel);
            app.EditField_2Label.Text = '';
            app.deviceid = uieditfield(app.ProductInformationPanel, 'text');
            app.EditField_3Label = uilabel(app.ProductInformationPanel);
            app.EditField_3Label.Text = '';
            app.serialnumber = uieditfield(app.ProductInformationPanel, 'text');
            app.EditField_4Label = uilabel(app.ProductInformationPanel);
            app.EditField_4Label.Text = '';
            app.hardwareversion = uieditfield(app.ProductInformationPanel, 'text');
            app.EditField_6Label = uilabel(app.ProductInformationPanel);
            app.EditField_6Label.Text = '';
            app.scmversion = uieditfield(app.ProductInformationPanel, 'text');
            app.EditField_7Label = uilabel(app.ProductInformationPanel);
            app.EditField_7Label.Text = '';
            app.protocolversion = uieditfield(app.ProductInformationPanel, 'text');
            app.EditField_8Label = uilabel(app.ProductInformationPanel);
            app.EditField_8Label.Text = '';
            app.licensekey = uieditfield(app.ProductInformationPanel, 'text');
            app.EditField_5Label = uilabel(app.ProductInformationPanel);
            app.EditField_5Label.Text = '';
            app.firmwareversion = uieditfield(app.ProductInformationPanel, 'text');
            app.ExitButton = uibutton(app.ConnectionTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ConfigureECGTab = uitab(app.TabGroup);
            app.ConfigureECGTab.Title = 'Configure ECG';
            app.MainsfrequencyButtonGroup = uibuttongroup(app.ConfigureECGTab);
            app.MainsfrequencyButtonGroup.Title = 'Mains frequency';
            app.Hz50Button = uiradiobutton(app.MainsfrequencyButtonGroup);
            app.Hz50Button.Text = '50 Hz';
            app.Hz50Button.Value = true;
            app.Hz60Button = uiradiobutton(app.MainsfrequencyButtonGroup);
            app.Hz60Button.Text = '60 Hz';
            app.SamplingrateButtonGroup = uibuttongroup(app.ConfigureECGTab);
            app.SamplingrateButtonGroup.Title = 'Sampling rate';
            app.Hz500Button = uiradiobutton(app.SamplingrateButtonGroup);
            app.Hz500Button.Text = '500 Hz';
            app.Hz500Button.Value = true;
            app.Hz1000Button = uiradiobutton(app.SamplingrateButtonGroup);
            app.Hz1000Button.Text = '1000 Hz';
            app.displayedECGDataPoints = uieditfield(app.ConfigureECGTab, 'numeric');
            app.DisplayedECGDataPointsLabel = uilabel(app.ConfigureECGTab);
            app.DisplayedECGDataPointsLabel.Text = 'Displayed ECG Data Points';
            app.ECGbuffersizeLabel = uilabel(app.ConfigureECGTab);
            app.ECGbuffersizeLabel.Text = 'ECG buffer size:';
            app.ECGbuffersizeSlider = uislider(app.ConfigureECGTab);
            app.ECGbuffersizeSlider.ValueChangingFcn = createCallbackFcn(app, @ECGbuffersizeSliderValueChanging, true);
            app.ThresholdderivativeLabel = uilabel(app.ConfigureECGTab);
            app.ThresholdderivativeLabel.Text = 'Threshold derivative:';
            app.ThresholdderivativeSlider = uislider(app.ConfigureECGTab);
            app.ThresholdderivativeSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdderivativeSliderValueChanging, true);
            app.MinimumRRintervalLabel = uilabel(app.ConfigureECGTab);
            app.MinimumRRintervalLabel.Text = 'Minimum RR interval:';
            app.MinimumRRintervalSlider = uislider(app.ConfigureECGTab);
            app.MinimumRRintervalSlider.ValueChangingFcn = createCallbackFcn(app, @MinimumRRintervalSliderValueChanging, true);
            app.MaximumRRintervalLabel = uilabel(app.ConfigureECGTab);
            app.MaximumRRintervalLabel.Text = 'Maximum RR interval:';
            app.MaximumRRintervalSlider = uislider(app.ConfigureECGTab);
            app.MaximumRRintervalSlider.ValueChangingFcn = createCallbackFcn(app, @MaximumRRintervalSliderValueChanging, true);
            app.PacemaxdurationLabel = uilabel(app.ConfigureECGTab);
            app.PacemaxdurationLabel.Text = 'Pace max duration:';
            app.PacemaxdurationSlider = uislider(app.ConfigureECGTab);
            app.PacemaxdurationSlider.ValueChangingFcn = createCallbackFcn(app, @PacemaxdurationSliderValueChanging, true);
            app.PacemindurationLabel = uilabel(app.ConfigureECGTab);
            app.PacemindurationLabel.Text = 'Pace min duration:';
            app.PacemindurationSlider = uislider(app.ConfigureECGTab);
            app.PacemindurationSlider.ValueChangingFcn = createCallbackFcn(app, @PacemindurationSliderValueChanging, true);
            app.ECGChannelsButtonGroup = uibuttongroup(app.ConfigureECGTab);
            app.ECGChannelsButtonGroup.Title = 'ECG Channels';
            app.oneChannel = uiradiobutton(app.ECGChannelsButtonGroup);
            app.oneChannel.Text = '1 channel';
            app.oneChannel.Value = true;
            app.threeSixChannels = uiradiobutton(app.ECGChannelsButtonGroup);
            app.threeSixChannels.Text = '3 / 6 channels';
            app.RWavedetectionButtonGroup = uibuttongroup(app.ConfigureECGTab);
            app.RWavedetectionButtonGroup.Title = 'R-Wave detection';
            app.rWaveDetectionOn = uiradiobutton(app.RWavedetectionButtonGroup);
            app.rWaveDetectionOn.Text = 'On';
            app.rWaveDetectionOn.Value = true;
            app.rWaveDetectionOff = uiradiobutton(app.RWavedetectionButtonGroup);
            app.rWaveDetectionOff.Text = 'Off';
            app.PacerdetectionButtonGroup = uibuttongroup(app.ConfigureECGTab);
            app.PacerdetectionButtonGroup.Title = 'Pacer detection';
            app.pacerDetectionOnS = uiradiobutton(app.PacerdetectionButtonGroup);
            app.pacerDetectionOnS.Text = 'On S';
            app.pacerDetectionOnS.Value = true;
            app.pacerDetectionOff = uiradiobutton(app.PacerdetectionButtonGroup);
            app.pacerDetectionOff.Text = 'Off';
            app.pacerDetectionOnB = uiradiobutton(app.PacerdetectionButtonGroup);
            app.pacerDetectionOnB.Text = 'On B';
            app.DeadtimeLabel = uilabel(app.ConfigureECGTab);
            app.DeadtimeLabel.Text = 'Dead time:';
            app.DeadtimeSlider = uislider(app.ConfigureECGTab);
            app.DeadtimeSlider.ValueChangingFcn = createCallbackFcn(app, @DeadtimeSliderValueChanging, true);
            app.bufferSizeSpinner = uispinner(app.ConfigureECGTab);
            app.bufferSizeSpinner.ValueChangingFcn = createCallbackFcn(app, @bufferSizeSpinnerValueChanging, true);
            app.minRRintervalSpinner = uispinner(app.ConfigureECGTab);
            app.minRRintervalSpinner.ValueChangingFcn = createCallbackFcn(app, @minRRintervalSpinnerValueChanging, true);
            app.maxRRintervalSpinner = uispinner(app.ConfigureECGTab);
            app.maxRRintervalSpinner.ValueChangingFcn = createCallbackFcn(app, @maxRRintervalSpinnerValueChanging, true);
            app.thresholdDerivativeSpinner = uispinner(app.ConfigureECGTab);
            app.thresholdDerivativeSpinner.ValueChangingFcn = createCallbackFcn(app, @thresholdDerivativeSpinnerValueChanging, true);
            app.deadTimeSpinner = uispinner(app.ConfigureECGTab);
            app.deadTimeSpinner.ValueChangingFcn = createCallbackFcn(app, @deadTimeSpinnerValueChanging, true);
            app.paceMinDurationSpinner = uispinner(app.ConfigureECGTab);
            app.paceMinDurationSpinner.ValueChangingFcn = createCallbackFcn(app, @paceMinDurationSpinnerValueChanging, true);
            app.paceMaxDurationSpinner = uispinner(app.ConfigureECGTab);
            app.paceMaxDurationSpinner.ValueChangingFcn = createCallbackFcn(app, @paceMaxDurationSpinnerValueChanging, true);
            app.MaximumECGderivativeLabel = uilabel(app.ConfigureECGTab);
            app.MaximumECGderivativeLabel.Text = 'Maximum ECG derivative:';
            app.MaximumECGderivativeSpinner = uispinner(app.ConfigureECGTab);
            app.ThresholdsteepSpinnerLabel = uilabel(app.ConfigureECGTab);
            app.ThresholdsteepSpinnerLabel.Text = 'Threshold steep:';
            app.ThresholdsteepSpinner = uispinner(app.ConfigureECGTab);
            app.ECGTab = uitab(app.TabGroup);
            app.ECGTab.Title = 'ECG';
            app.ECGInformationPanel = uipanel(app.ECGTab);
            app.ECGInformationPanel.Title = 'ECG Information';
            app.ReceivedPackagesLabel = uilabel(app.ECGInformationPanel);
            app.ReceivedPackagesLabel.Text = 'Received Packages';
            app.BrokenPackagesLabel = uilabel(app.ECGInformationPanel);
            app.BrokenPackagesLabel.Text = 'Broken Packages';
            app.RunTimesLabel = uilabel(app.ECGInformationPanel);
            app.RunTimesLabel.Text = 'Run Time [s]';
            app.runtime = uieditfield(app.ECGInformationPanel, 'numeric');
            app.brokenPackages = uieditfield(app.ECGInformationPanel, 'numeric');
            app.receivedPackages = uieditfield(app.ECGInformationPanel, 'numeric');
            app.LostPackagesLabel = uilabel(app.ECGInformationPanel);
            app.LostPackagesLabel.Text = 'Lost Packages';
            app.lostPackages = uieditfield(app.ECGInformationPanel, 'numeric');
            app.HeartRateBpmLabel = uilabel(app.ECGInformationPanel);
            app.HeartRateBpmLabel.Text = 'Heart Rate [Bpm]';
            app.heartRate = uieditfield(app.ECGInformationPanel, 'numeric');
            app.StopButton = uibutton(app.ECGTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.ECGTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ECGGraphPanel_2 = uipanel(app.ECGTab);
            app.ECGGraphPanel_2.Title = 'ECG Graph';
            app.UIAxes_4 = uiaxes(app.ECGGraphPanel_2);
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes_3 = uiaxes(app.ECGGraphPanel_2);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_2 = uiaxes(app.ECGGraphPanel_2);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_6 = uiaxes(app.ECGGraphPanel_2);
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            app.UIAxes_5 = uiaxes(app.ECGGraphPanel_2);
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            app.UIAxes = uiaxes(app.ECGGraphPanel_2);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ECGGraphPanel = uipanel(app.ECGTab);
            app.ECGGraphPanel.Title = 'ECG Graph';
            app.UIAxes_7 = uiaxes(app.ECGGraphPanel);
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            app.BandpassFilter01225HzCheckBox = uicheckbox(app.ECGTab);
            app.BandpassFilter01225HzCheckBox.Text = 'Bandpass Filter (0,12 - 25 Hz)';
            app.ShowRpeaksCheckBox = uicheckbox(app.ECGTab);
            app.ShowRpeaksCheckBox.Text = 'Show R peaks';
            app.ShowpacersCheckBox = uicheckbox(app.ECGTab);
            app.ShowpacersCheckBox.Text = 'Show pacers';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ECG_GUI_v3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end